classdef NewApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AudioFileEditField          matlab.ui.control.EditField
        AudioFileEditFieldLabel     matlab.ui.control.Label
        ObtainAudioHistogram        matlab.ui.control.Button
        LoadFile                    matlab.ui.control.Button
        FilterCheckBox              matlab.ui.control.CheckBox
        ShowROICheckBox             matlab.ui.control.CheckBox
        ShowDropDown                matlab.ui.control.DropDown
        ShowDropDownLabel           matlab.ui.control.Label
        BandselectionDropDown       matlab.ui.control.DropDown
        BandselectionDropDownLabel  matlab.ui.control.Label
        InputFileEditField          matlab.ui.control.EditField
        SelectInputFileButton       matlab.ui.control.Button
        PO7_Button                  matlab.ui.control.StateButton
        O1_Button                   matlab.ui.control.StateButton
        PO3_Button                  matlab.ui.control.StateButton
        P7_Button                   matlab.ui.control.StateButton
        P5_Button                   matlab.ui.control.StateButton
        P3_Button                   matlab.ui.control.StateButton
        P1_Button                   matlab.ui.control.StateButton
        CP5_Button                  matlab.ui.control.StateButton
        CP3_Button                  matlab.ui.control.StateButton
        PO8_Button                  matlab.ui.control.StateButton
        O2_Button                   matlab.ui.control.StateButton
        PO4_Button                  matlab.ui.control.StateButton
        P8_Button                   matlab.ui.control.StateButton
        P6_Button                   matlab.ui.control.StateButton
        P4_Button                   matlab.ui.control.StateButton
        P2_Button                   matlab.ui.control.StateButton
        TP8_Button                  matlab.ui.control.StateButton
        CP6_Button                  matlab.ui.control.StateButton
        CP4_Button                  matlab.ui.control.StateButton
        FT7_Button                  matlab.ui.control.StateButton
        FC5_Button                  matlab.ui.control.StateButton
        FC3_Button                  matlab.ui.control.StateButton
        TP7_Button                  matlab.ui.control.StateButton
        AF3_Button                  matlab.ui.control.StateButton
        F1_Button                   matlab.ui.control.StateButton
        AF7_Button                  matlab.ui.control.StateButton
        Fp1_Button                  matlab.ui.control.StateButton
        AF8_Button                  matlab.ui.control.StateButton
        F5_Button                   matlab.ui.control.StateButton
        F7_Button                   matlab.ui.control.StateButton
        F3_Button                   matlab.ui.control.StateButton
        Fp2_Button                  matlab.ui.control.StateButton
        F8_Button                   matlab.ui.control.StateButton
        F6_Button                   matlab.ui.control.StateButton
        F4_Button                   matlab.ui.control.StateButton
        F2_Button                   matlab.ui.control.StateButton
        FT8_Button                  matlab.ui.control.StateButton
        FC6_Button                  matlab.ui.control.StateButton
        AF4_Button                  matlab.ui.control.StateButton
        FC4_Button                  matlab.ui.control.StateButton
        CP2_Button                  matlab.ui.control.StateButton
        CP1_Button                  matlab.ui.control.StateButton
        FC2_Button                  matlab.ui.control.StateButton
        FC1_Button                  matlab.ui.control.StateButton
        CPz_Button                  matlab.ui.control.StateButton
        Pz_Button                   matlab.ui.control.StateButton
        Oz_Button                   matlab.ui.control.StateButton
        Fpz_Button                  matlab.ui.control.StateButton
        Fz_Button                   matlab.ui.control.StateButton
        FCz_Button                  matlab.ui.control.StateButton
        C1_Button                   matlab.ui.control.StateButton
        C3_Button                   matlab.ui.control.StateButton
        C5_Button                   matlab.ui.control.StateButton
        T7_Button                   matlab.ui.control.StateButton
        T8_Button                   matlab.ui.control.StateButton
        C6_Button                   matlab.ui.control.StateButton
        C4_Button                   matlab.ui.control.StateButton
        C2_Button                   matlab.ui.control.StateButton
        Cz_Button                   matlab.ui.control.StateButton
    end

    
    properties (Access = private)
          %Channels
        Fp1;
        Fpz;
        AF7;
        AF3;
        AF4;
        AF8;
        F7;
        F5;
        F3;
        F1;
        Fz;
        F2;
        F4;
        F6;
        F8;
        Fp2;
        FT7;
        FC5;
        FC3;
        FC1;
        FCz;
        FC2;
        FC4;
        FC6;
        FT8;
        T7;
        C5;
        C3;
        C1;
        Cz;
        C2;
        C4;
        C6;
        T8;
        TP7;
        CP5;
        CP3;
        CP1;
        CPz;
        CP2;
        CP4;
        CP6;
        TP8;
        P7;
        P5;
        P3;
        P1;
        Pz;
        P2;
        P4;
        P6;
        P8;
        PO7;
        PO3;
        PO4;
        PO8;
        O1;
        Oz;
        O2;
        HEOGplus;
        HEOG;
        VEOGplus;
        VEOG;
        M1;
        
        %filtred channels
        %Channels
        Fp1_filtred;
        Fpz_filtred;
        AF7_filtred;
        AF3_filtred;
        AF4_filtred;
        AF8_filtred;
        F7_filtred;
        F5_filtred;
        F3_filtred;
        F1_filtred;
        Fz_filtred;
        F2_filtred;
        F4_filtred;
        F6_filtred;
        F8_filtred;
        Fp2_filtred;
        FT7_filtred;
        FC5_filtred;
        FC3_filtred;
        FC1_filtred;
        FCz_filtred;
        FC2_filtred;
        FC4_filtred;
        FC6_filtred;
        FT8_filtred;
        T7_filtred;
        C5_filtred;
        C3_filtred;
        C1_filtred;
        Cz_filtred;
        C2_filtred;
        C4_filtred;
        C6_filtred;
        T8_filtred;
        TP7_filtred;
        CP5_filtred;
        CP3_filtred;
        CP1_filtred;
        CPz_filtred;
        CP2_filtred;
        CP4_filtred;
        CP6_filtred;
        TP8_filtred;
        P7_filtred;
        P5_filtred;
        P3_filtred;
        P1_filtred;
        Pz_filtred;
        P2_filtred;
        P4_filtred;
        P6_filtred;
        P8_filtred;
        PO7_filtred;
        PO3_filtred;
        PO4_filtred;
        PO8_filtred;
        O1_filtred;
        Oz_filtred;
        O2_filtred;
        HEOGplus_filtred;
        HEOG_filtred;
        VEOGplus_filtred;
        VEOG_filtred;
        M1_filtred;
        
        Fp1MI;
        FpzMI;
        AF7MI;
        AF3MI;
        AF4MI;
        AF8MI;
        F7MI;
        F5MI;
        F3MI;
        F1MI;
        FzMI;
        F2MI;
        F4MI;
        F6MI;
        F8MI;
        Fp2MI;
        FT7MI;
        FC5MI;
        FC3MI;
        FC1MI;
        FCzMI;
        FC2MI;
        FC4MI;
        FC6MI;
        FT8MI;
        T7MI;
        C5MI;
        C3MI;
        C1MI;
        CzMI;
        C2MI;
        C4MI;
        C6MI;
        T8MI;
        TP7MI;
        CP5MI;
        CP3MI;
        CP1MI;
        CPzMI;
        CP2MI;
        CP4MI;
        CP6MI;
        TP8MI;
        P7MI;
        P5MI;
        P3MI;
        P1MI;
        PzMI;
        P2MI;
        P4MI;
        P6MI;
        P8MI;
        PO7MI;
        PO3MI;
        PO4MI;
        PO8MI;
        O1MI;
        OzMI;
        O2MI;
         % Description
        InputFile;
       InputPath; % Description
        SelectedBand; % Description
        FileSelected =0;% Description
        InputFileWithPath; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectInputFileButton
        function SelectInputFileButtonPushed(app, event)
            
               % Display uigetfile dialog
            filterspec = {'*.json;*.csv;*.txt;*.xls;*.xlsx;*.mat','Select File'};
            % open dummy figure for solving bug from matlab
            f=figure();
            %bring figure to front focus
            drawnow;
            f.Visible='off';
            [app.InputFile,app.InputPath] = uigetfile(filterspec);
            % delete dummy figure for solving bug from matlab
            delete(f);
            if isequal (app.InputFile, 0)
                return
            else
                app.FileSelected=1;
                app.InputFileWithPath= fullfile(app.InputPath,app.InputFile);
                text = fileread(app.InputFileWithPath);
                data_from_file=jsondecode(text);

                %app.filein= strcat(Path,Filename);
            
            app.InputFileEditField.Value=app.InputFile;
            end
        end

        % Value changed function: BandselectionDropDown
        function BandselectionDropDownValueChanged(app, event)
            app.SelectedBand = app.BandselectionDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Cz_Button = uibutton(app.UIFigure, 'state');
            app.Cz_Button.Text = '*';
            app.C2_Button = uibutton(app.UIFigure, 'state');
            app.C2_Button.Text = '*';
            app.C4_Button = uibutton(app.UIFigure, 'state');
            app.C4_Button.Text = '*';
            app.C6_Button = uibutton(app.UIFigure, 'state');
            app.C6_Button.Text = '*';
            app.T8_Button = uibutton(app.UIFigure, 'state');
            app.T8_Button.Text = '*';
            app.T7_Button = uibutton(app.UIFigure, 'state');
            app.T7_Button.Text = '*';
            app.C5_Button = uibutton(app.UIFigure, 'state');
            app.C5_Button.Text = '*';
            app.C3_Button = uibutton(app.UIFigure, 'state');
            app.C3_Button.Text = '*';
            app.C1_Button = uibutton(app.UIFigure, 'state');
            app.C1_Button.Text = '*';
            app.FCz_Button = uibutton(app.UIFigure, 'state');
            app.FCz_Button.Text = '*';
            app.Fz_Button = uibutton(app.UIFigure, 'state');
            app.Fz_Button.Text = '*';
            app.Fpz_Button = uibutton(app.UIFigure, 'state');
            app.Fpz_Button.Text = '*';
            app.Oz_Button = uibutton(app.UIFigure, 'state');
            app.Oz_Button.Text = '*';
            app.Pz_Button = uibutton(app.UIFigure, 'state');
            app.Pz_Button.Text = '*';
            app.CPz_Button = uibutton(app.UIFigure, 'state');
            app.CPz_Button.Text = '*';
            app.FC1_Button = uibutton(app.UIFigure, 'state');
            app.FC1_Button.Text = '*';
            app.FC2_Button = uibutton(app.UIFigure, 'state');
            app.FC2_Button.Text = '*';
            app.CP1_Button = uibutton(app.UIFigure, 'state');
            app.CP1_Button.Text = '*';
            app.CP2_Button = uibutton(app.UIFigure, 'state');
            app.CP2_Button.Text = '*';
            app.FC4_Button = uibutton(app.UIFigure, 'state');
            app.FC4_Button.Text = '*';
            app.AF4_Button = uibutton(app.UIFigure, 'state');
            app.AF4_Button.Text = '*';
            app.FC6_Button = uibutton(app.UIFigure, 'state');
            app.FC6_Button.Text = '*';
            app.FT8_Button = uibutton(app.UIFigure, 'state');
            app.FT8_Button.Text = '*';
            app.F2_Button = uibutton(app.UIFigure, 'state');
            app.F2_Button.Text = '*';
            app.F4_Button = uibutton(app.UIFigure, 'state');
            app.F4_Button.Text = '*';
            app.F6_Button = uibutton(app.UIFigure, 'state');
            app.F6_Button.Text = '*';
            app.F8_Button = uibutton(app.UIFigure, 'state');
            app.F8_Button.Text = '*';
            app.Fp2_Button = uibutton(app.UIFigure, 'state');
            app.Fp2_Button.Text = '*';
            app.F3_Button = uibutton(app.UIFigure, 'state');
            app.F3_Button.Text = '*';
            app.F7_Button = uibutton(app.UIFigure, 'state');
            app.F7_Button.Text = '*';
            app.F5_Button = uibutton(app.UIFigure, 'state');
            app.F5_Button.Text = '*';
            app.AF8_Button = uibutton(app.UIFigure, 'state');
            app.AF8_Button.Text = '*';
            app.Fp1_Button = uibutton(app.UIFigure, 'state');
            app.Fp1_Button.Text = '*';
            app.AF7_Button = uibutton(app.UIFigure, 'state');
            app.AF7_Button.Text = '*';
            app.F1_Button = uibutton(app.UIFigure, 'state');
            app.F1_Button.Text = '*';
            app.AF3_Button = uibutton(app.UIFigure, 'state');
            app.AF3_Button.Text = '*';
            app.TP7_Button = uibutton(app.UIFigure, 'state');
            app.TP7_Button.Text = '*';
            app.FC3_Button = uibutton(app.UIFigure, 'state');
            app.FC3_Button.Text = '*';
            app.FC5_Button = uibutton(app.UIFigure, 'state');
            app.FC5_Button.Text = '*';
            app.FT7_Button = uibutton(app.UIFigure, 'state');
            app.FT7_Button.Text = '*';
            app.CP4_Button = uibutton(app.UIFigure, 'state');
            app.CP4_Button.Text = '*';
            app.CP6_Button = uibutton(app.UIFigure, 'state');
            app.CP6_Button.Text = '*';
            app.TP8_Button = uibutton(app.UIFigure, 'state');
            app.TP8_Button.Text = '*';
            app.P2_Button = uibutton(app.UIFigure, 'state');
            app.P2_Button.Text = '*';
            app.P4_Button = uibutton(app.UIFigure, 'state');
            app.P4_Button.Text = '*';
            app.P6_Button = uibutton(app.UIFigure, 'state');
            app.P6_Button.Text = '*';
            app.P8_Button = uibutton(app.UIFigure, 'state');
            app.P8_Button.Text = '*';
            app.PO4_Button = uibutton(app.UIFigure, 'state');
            app.PO4_Button.Text = '*';
            app.O2_Button = uibutton(app.UIFigure, 'state');
            app.O2_Button.Text = '*';
            app.PO8_Button = uibutton(app.UIFigure, 'state');
            app.PO8_Button.Text = '*';
            app.CP3_Button = uibutton(app.UIFigure, 'state');
            app.CP3_Button.Text = '*';
            app.CP5_Button = uibutton(app.UIFigure, 'state');
            app.CP5_Button.Text = '*';
            app.P1_Button = uibutton(app.UIFigure, 'state');
            app.P1_Button.Text = '*';
            app.P3_Button = uibutton(app.UIFigure, 'state');
            app.P3_Button.Text = '*';
            app.P5_Button = uibutton(app.UIFigure, 'state');
            app.P5_Button.Text = '*';
            app.P7_Button = uibutton(app.UIFigure, 'state');
            app.P7_Button.Text = '*';
            app.PO3_Button = uibutton(app.UIFigure, 'state');
            app.PO3_Button.Text = '*';
            app.O1_Button = uibutton(app.UIFigure, 'state');
            app.O1_Button.Text = '*';
            app.PO7_Button = uibutton(app.UIFigure, 'state');
            app.PO7_Button.Text = '*';
            app.SelectInputFileButton = uibutton(app.UIFigure, 'push');
            app.SelectInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputFileButtonPushed, true);
            app.SelectInputFileButton.Text = 'Select Input File';
            app.InputFileEditField = uieditfield(app.UIFigure, 'text');
            app.InputFileEditField.Value = 'Push Load File Button';
            app.BandselectionDropDownLabel = uilabel(app.UIFigure);
            app.BandselectionDropDownLabel.Text = 'Band selection';
            app.BandselectionDropDown = uidropdown(app.UIFigure);
            app.BandselectionDropDown.Items = {'delta', 'theta low', 'theta high'};
            app.BandselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @BandselectionDropDownValueChanged, true);
            app.BandselectionDropDown.Value = 'delta';
            app.ShowDropDownLabel = uilabel(app.UIFigure);
            app.ShowDropDownLabel.Text = 'Show:';
            app.ShowDropDown = uidropdown(app.UIFigure);
            app.ShowDropDown.Items = {'Tension signal', 'Mean values'};
            app.ShowDropDown.Value = 'Tension signal';
            app.ShowROICheckBox = uicheckbox(app.UIFigure);
            app.ShowROICheckBox.Text = 'Show ROI';
            app.FilterCheckBox = uicheckbox(app.UIFigure);
            app.FilterCheckBox.Text = 'Filter';
            app.LoadFile = uibutton(app.UIFigure, 'push');
            app.LoadFile.Text = 'Load Selected File';
            app.ObtainAudioHistogram = uibutton(app.UIFigure, 'push');
            app.ObtainAudioHistogram.Text = 'Audio Histogram';
            app.AudioFileEditFieldLabel = uilabel(app.UIFigure);
            app.AudioFileEditFieldLabel.Text = 'Audio File';
            app.AudioFileEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end